classdef ImageFillter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        highLable        matlab.ui.control.Label
        lowLabel         matlab.ui.control.Label
        BlueHighSlider   matlab.ui.control.Slider
        GreenHighSlider  matlab.ui.control.Slider
        BlueLowSlider    matlab.ui.control.Slider
        GreenLowSlider   matlab.ui.control.Slider
        RedLowSlider     matlab.ui.control.Slider
        RedHighSlider    matlab.ui.control.Slider
        NoiseSlider      matlab.ui.control.Slider
        Label            matlab.ui.control.Label
        StatusSwitch     matlab.ui.control.Switch
        Image            matlab.ui.control.Image
        ContextMenu      matlab.ui.container.ContextMenu
        Menu             matlab.ui.container.Menu
        Menu2            matlab.ui.container.Menu
    end

    
    properties (Access = private)
    originalImage;  % Use more descriptive names
    modifiedImage;
    tempImage;
    noiseLevel;
    exitFlag;
    colorRange;  
    end
    
    methods (Access = private)
        
      function disableComponents(app)
        app.NoiseSlider.Enable = 'off';
        app.RedLowSlider.Enable = 'off';
        app.RedHighSlider.Enable = 'off';
        app.GreenLowSlider.Enable = 'off';
        app.GreenHighSlider.Enable = 'off';
        app.BlueLowSlider.Enable = 'off';
        app.BlueHighSlider.Enable = 'off';
        if  app.exitFlag == 0
        app.StatusSwitch.Enable = 'off';
        end
      end


    function enableComponents(app)
        app.NoiseSlider.Enable = 'on';
        app.RedLowSlider.Enable = 'on';
        app.RedHighSlider.Enable = 'on';
        app.GreenLowSlider.Enable = 'on';
        app.GreenHighSlider.Enable = 'on';
        app.BlueLowSlider.Enable = 'on';
        app.BlueHighSlider.Enable = 'on';
        app.StatusSwitch.Enable = 'on';
    end
    function updateColorRange(app, sliderColor, sliderValue)

        switch sliderColor
            case 'RedLow'
                app.colorRange(1, 1) = sliderValue;
            case 'RedHigh'
                app.colorRange(2, 1) = sliderValue;
            case 'GreenLow'
                app.colorRange(1, 2) = sliderValue;
            case 'GreenHigh'
                app.colorRange(2, 2) = sliderValue;
            case 'BlueLow'
                app.colorRange(1, 3) = sliderValue;
            case 'BlueHigh'
                app.colorRange(2, 3) = sliderValue;
        end
        

        app.tempImage = imadjust(app.modifiedImage, app.colorRange, []);
        app.Image.ImageSource = app.tempImage;
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.exitFlag = 0;
            app.colorRange = [app.RedLowSlider.Value app.GreenLowSlider.Value app.BlueLowSlider.Value; ...
                              app.RedHighSlider.Value app.GreenHighSlider.Value app.BlueHighSlider.Value];
            
    
            disableComponents(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        [fileName, pathName] = uigetfile({'*.jpg;*.png;*.bmp;*.gif;*.tif'}, 'Select Image');
          if fileName
            app.originalImage = imread(fullfile(pathName, fileName));
            app.modifiedImage = app.originalImage;
            app.Image.ImageSource = app.originalImage;
            

            enableComponents(app);
    
           end

        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
                app.noiseLevel = app.NoiseSlider.Value;
                if app.exitFlag == 0
                    if app.noiseLevel > 0
                        app.tempImage = imnoise(app.modifiedImage, 'gaussian', app.noiseLevel);
                    else
                        app.tempImage = app.originalImage;
                    end
                    app.Image.ImageSource = app.tempImage;
                end


        end

        % Value changed function: StatusSwitch
        function StatusSwitchValueChanged(app, event)
            value = app.StatusSwitch.Value;
            if strcmp(value, 'قبل')
                app.Image.ImageSource = app.originalImage;
                app.exitFlag = 1;
                disableComponents(app);
            else
                app.Image.ImageSource = app.tempImage;
                app.exitFlag = 0;
                enableComponents(app);
            end
        end

        % Value changed function: RedLowSlider
        function RedLowSliderValueChanged(app, event)
            updateColorRange(app, 'RedLow', app.RedLowSlider.Value);
        end

        % Value changed function: GreenLowSlider
        function GreenLowSliderValueChanged(app, event)
            updateColorRange(app, 'GreenLow', app.GreenLowSlider.Value);
        end

        % Value changed function: BlueLowSlider
        function BlueLowSliderValueChanged(app, event)
            updateColorRange(app, 'BlueLow', app.BlueLowSlider.Value);
        end

        % Value changed function: RedHighSlider
        function RedHighSliderValueChanged(app, event)
            updateColorRange(app, 'RedHigh', app.RedHighSlider.Value);
        end

        % Value changed function: GreenHighSlider
        function GreenHighSliderValueChanged(app, event)
            updateColorRange(app, 'GreenHigh', app.GreenHighSlider.Value);
        end

        % Value changed function: BlueHighSlider
        function BlueHighSliderValueChanged(app, event)
                updateColorRange(app, 'BlueHigh', app.BlueHighSlider.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Capture.PNG');
            app.StatusSwitch = uiswitch(app.UIFigure, 'slider');
            app.StatusSwitch.Items = {'بعد', 'قبل'};
            app.StatusSwitch.ValueChangedFcn = createCallbackFcn(app, @StatusSwitchValueChanged, true);
            app.StatusSwitch.Value = 'بعد';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'نويز';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0 1];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'أضافة تباين';
            app.RedHighSlider = uislider(app.Tab);
            app.RedHighSlider.Limits = [0.5 1];
            app.RedHighSlider.ValueChangedFcn = createCallbackFcn(app, @RedHighSliderValueChanged, true);
            app.RedHighSlider.Value = 0.5;
            app.RedLowSlider = uislider(app.Tab);
            app.RedLowSlider.Limits = [0 0.49];
            app.RedLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.49];
            app.RedLowSlider.ValueChangedFcn = createCallbackFcn(app, @RedLowSliderValueChanged, true);
            app.GreenLowSlider = uislider(app.Tab);
            app.GreenLowSlider.Limits = [0 0.49];
            app.GreenLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.49];
            app.GreenLowSlider.ValueChangedFcn = createCallbackFcn(app, @GreenLowSliderValueChanged, true);
            app.BlueLowSlider = uislider(app.Tab);
            app.BlueLowSlider.Limits = [0 0.49];
            app.BlueLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.49];
            app.BlueLowSlider.ValueChangedFcn = createCallbackFcn(app, @BlueLowSliderValueChanged, true);
            app.GreenHighSlider = uislider(app.Tab);
            app.GreenHighSlider.Limits = [0.5 1];
            app.GreenHighSlider.ValueChangedFcn = createCallbackFcn(app, @GreenHighSliderValueChanged, true);
            app.GreenHighSlider.Value = 0.5;
            app.BlueHighSlider = uislider(app.Tab);
            app.BlueHighSlider.Limits = [0.5 1];
            app.BlueHighSlider.ValueChangedFcn = createCallbackFcn(app, @BlueHighSliderValueChanged, true);
            app.BlueHighSlider.Value = 0.5;
            app.lowLabel = uilabel(app.Tab);
            app.lowLabel.Text = 'منخفظ';
            app.highLable = uilabel(app.Tab);
            app.highLable.Text = 'مرتفع';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFillter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end